classdef Project_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        IntoductionTab                 matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        MaksudurRahamanTurzoLabel      matlab.ui.control.Label
        JoyontaKartikDebnathLabel      matlab.ui.control.Label
        withLabel                      matlab.ui.control.Label
        DoublePendulumLabel            matlab.ui.control.Label
        UnderstandingtheButterflyEffectLabel  matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        TheoriticalDiscussionTab       matlab.ui.container.Tab
        TextArea_2                     matlab.ui.control.TextArea
        TheoryLabel_2                  matlab.ui.control.Label
        SinglePendulumTab              matlab.ui.container.Tab
        SinglePendulumLabel            matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ColoroftheBallDropDown         matlab.ui.control.DropDown
        ColoroftheBallDropDownLabel    matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Image                          matlab.ui.control.Image
        SkipButton                     matlab.ui.control.Button
        AngularvelocityEditField       matlab.ui.control.NumericEditField
        AngularvelocityEditFieldLabel  matlab.ui.control.Label
        AngleinDegreesEditField        matlab.ui.control.NumericEditField
        AngleinDegreesEditFieldLabel   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        FrameRateEditField             matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel        matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        gEditField                     matlab.ui.control.NumericEditField
        gEditFieldLabel                matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        MassEditFieldLabel             matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        DoublePendulumTab              matlab.ui.container.Tab
        CopyButton                     matlab.ui.control.Button
        TextArea_3                     matlab.ui.control.TextArea
        BallBLabel                     matlab.ui.control.Label
        BallALabel                     matlab.ui.control.Label
        DoublePendulumLabel_2          matlab.ui.control.Label
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        ColorDropDown_4                matlab.ui.control.DropDown
        ColorDropDown_5                matlab.ui.control.DropDown
        ColorDropDown_5Label           matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        SkipButton_2                   matlab.ui.control.Button
        RunButton_2                    matlab.ui.control.Button
        FramerateEditField             matlab.ui.control.NumericEditField
        FramerateEditFieldLabel        matlab.ui.control.Label
        TimeEditField_2                matlab.ui.control.NumericEditField
        TimeEditField_2Label           matlab.ui.control.Label
        gEditField_2                   matlab.ui.control.NumericEditField
        gEditField_2Label              matlab.ui.control.Label
        AngularvelocityEditField_2     matlab.ui.control.NumericEditField
        AngularvelocityEditField_2Label  matlab.ui.control.Label
        AngularvelocityAEditField      matlab.ui.control.NumericEditField
        InitialAngleEditField          matlab.ui.control.NumericEditField
        InitialAngleEditFieldLabel     matlab.ui.control.Label
        AngleofAEditField              matlab.ui.control.NumericEditField
        LengthEditField_2              matlab.ui.control.NumericEditField
        LengthEditField_2Label         matlab.ui.control.Label
        LengthAEditField               matlab.ui.control.NumericEditField
        MassEditField_3                matlab.ui.control.NumericEditField
        MassEditField_3Label           matlab.ui.control.Label
        MassEditField_2                matlab.ui.control.NumericEditField
        UIAxes_2                       matlab.ui.control.UIAxes
        ButterflyEffectTab             matlab.ui.container.Tab
        TextArea_4                     matlab.ui.control.TextArea
        ButterflyEffectLabel           matlab.ui.control.Label
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        ColorDropDown_3                matlab.ui.control.DropDown
        ColorDropDown_2                matlab.ui.control.DropDown
        ColorDropDown_2Label           matlab.ui.control.Label
        SkipButton_3                   matlab.ui.control.Button
        RunButton_3                    matlab.ui.control.Button
        PendulumLabel                  matlab.ui.control.Label
        PendulumLabel_2                matlab.ui.control.Label
        AvelocityBEditField_2          matlab.ui.control.NumericEditField
        AvelocityAEditField_2          matlab.ui.control.NumericEditField
        AngleofBEditField_3            matlab.ui.control.NumericEditField
        AngleofAEditField_3            matlab.ui.control.NumericEditField
        LengthBEditField_3             matlab.ui.control.NumericEditField
        LengthAEditField_3             matlab.ui.control.NumericEditField
        MassBEditField_3               matlab.ui.control.NumericEditField
        MassAEditField_3               matlab.ui.control.NumericEditField
        FramerateEditField_2           matlab.ui.control.NumericEditField
        FramerateEditField_2Label      matlab.ui.control.Label
        TimeEditField_3                matlab.ui.control.NumericEditField
        TimeEditField_3Label           matlab.ui.control.Label
        gEditField_3                   matlab.ui.control.NumericEditField
        gEditField_3Label              matlab.ui.control.Label
        AvelocityBEditField            matlab.ui.control.NumericEditField
        AvelocityBEditFieldLabel       matlab.ui.control.Label
        AvelocityAEditField            matlab.ui.control.NumericEditField
        AvelocityAEditFieldLabel       matlab.ui.control.Label
        AngleofBEditField_2            matlab.ui.control.NumericEditField
        AngleofBEditField_2Label       matlab.ui.control.Label
        AngleofAEditField_2            matlab.ui.control.NumericEditField
        AngleofAEditField_2Label       matlab.ui.control.Label
        LengthBEditField_2             matlab.ui.control.NumericEditField
        LengthBEditField_2Label        matlab.ui.control.Label
        LengthAEditField_2             matlab.ui.control.NumericEditField
        LengthAEditField_2Label        matlab.ui.control.Label
        MassBEditField_2               matlab.ui.control.NumericEditField
        MassBEditField_2Label          matlab.ui.control.Label
        MassAEditField_2               matlab.ui.control.NumericEditField
        MassAEditField_2Label          matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        ComparisonofIterationsTab      matlab.ui.container.Tab
        TextArea_5                     matlab.ui.control.TextArea
        ComparisonofIterativeMethodsLabel  matlab.ui.control.Label
        DropDown_6                     matlab.ui.control.DropDown
        DropDown_5                     matlab.ui.control.DropDown
        DropDown_4                     matlab.ui.control.DropDown
        Lamp_11                        matlab.ui.control.Lamp
        Lamp_10                        matlab.ui.control.Lamp
        Lamp_9                         matlab.ui.control.Lamp
        ImprovedEulerMethodCheckBox    matlab.ui.control.CheckBox
        EulerMethodCheckBox            matlab.ui.control.CheckBox
        BuiltinFunctionCheckBox        matlab.ui.control.CheckBox
        SkipButton_5                   matlab.ui.control.Button
        RunButton_4                    matlab.ui.control.Button
        FramerateEditField_3           matlab.ui.control.NumericEditField
        FramerateEditField_3Label      matlab.ui.control.Label
        TimeEditField_4                matlab.ui.control.NumericEditField
        TimeEditField_4Label           matlab.ui.control.Label
        gEditField_4                   matlab.ui.control.NumericEditField
        gEditField_4Label              matlab.ui.control.Label
        AngularvelocityBEditField_2    matlab.ui.control.NumericEditField
        AngularvelocityBEditField_2Label  matlab.ui.control.Label
        AngularvelocityAEditField_2    matlab.ui.control.NumericEditField
        AngularvelocityAEditField_2Label  matlab.ui.control.Label
        AngleofBEditField_6            matlab.ui.control.NumericEditField
        AngleofBEditField_6Label       matlab.ui.control.Label
        AngleofAEditField_6            matlab.ui.control.NumericEditField
        AngleofAEditField_6Label       matlab.ui.control.Label
        LengthBEditField_6             matlab.ui.control.NumericEditField
        LengthBEditField_6Label        matlab.ui.control.Label
        LengthAEditField_6             matlab.ui.control.NumericEditField
        LengthAEditField_6Label        matlab.ui.control.Label
        MassBEditField_6               matlab.ui.control.NumericEditField
        MassBEditField_6Label          matlab.ui.control.Label
        MassAEditField_6               matlab.ui.control.NumericEditField
        MassAEditField_6Label          matlab.ui.control.Label
        UIAxes_7                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        skip;
    end
    
    methods (Access = private)
        function dx = double_pendulum_model(app,t,y,parameters)

    mA  = parameters(1);
    mB  = parameters(2);
    lA  = parameters(3);
    lB  = parameters(4);
    g   = parameters(5);
   
    y1     = y(1);
    y2     = y(2);
    y3     = y(3);
    y4     = y(4);

    out(1) = y2;
    out(2) = (  (mA+mB)*lA - mB*lA*(cos(y1-y3))^2  )^(-1) * ...
        (  -mB*lA*(y2^2)*sin(y1-y3)*cos(y1-y3)  + mB*g*sin(y3)*cos(y1-y3) ...
        - mB*lB*(y4^2)*sin(y1-y3)  -  (mA+mB)*g*sin(y1)  );
    out(3) = y4;
    out(4) = (  lB - (mB*lB/(mA+mB))*(cos(y1-y3))^2 )^(-1)  * ...
        ( lA*(y2)^2*sin(y1-y3) - g*sin(y3) + (mB*lB/(mA+mB))*(y4^2)*...
        sin(y1-y3)*cos(y1-y3) + g*sin(y1)*cos(y1-y3) );
    
    dx = [out(1) ; out(2) ; out(3) ; out(4)];
    end

function [t , results] = solution_to_double_pendulam...
    (app,mA , mB , lA , lB , g , theta_A0 , theta_B0 ,...
    velocity_A0 , velocity_B0 , tf , fR )

    parameters = [mA mB lA lB g];
    theta_0 = [theta_A0 velocity_A0 theta_B0 velocity_B0];
                              
    dt      = 1/fR;                 
    time_span    = 0 : dt : tf ;

    [ t , results ] = ode45(@(time,theta) double_pendulum_model(app,time,...
        theta,parameters),time_span,theta_0);

end 
    end

    methods (Access = private)

        function startupFcn(app)
            axis(app.UIAxes,'equal')
            app.UIAxes.set('XLim',[-1 1])
            app.UIAxes.set('YLim',[-1 1])
            app.UIAxes.set('Color','k')

            axis(app.UIAxes_2,'equal')
            app.UIAxes_2.set('XLim',[-1 1])
            app.UIAxes_2.set('YLim',[-1 1])
            app.UIAxes_2.set('Color','k')

            axis(app.UIAxes_3,'equal')
            app.UIAxes_3.set('XLim',[-1 1])
            app.UIAxes_3.set('YLim',[-1 1])
            app.UIAxes_3.set('Color','k')

            axis(app.UIAxes_7,'equal')
            app.UIAxes_7.set('XLim',[-1 1])
            app.UIAxes_7.set('YLim',[-1 1])
            app.UIAxes_7.set('Color','k')

            app.Lamp.Color = app.ColoroftheBallDropDown.Value;
            app.Lamp_2.Color = app.ColorDropDown_4.Value;
            app.Lamp_3.Color = app.ColorDropDown_5.Value;
            app.Lamp_4.Color = app.ColorDropDown_3.Value;
            app.Lamp_5.Color = app.ColorDropDown_2.Value;
            app.Lamp_11.Color = app.DropDown_4.Value;
            app.Lamp_10.Color = app.DropDown_5.Value;
            app.Lamp_9.Color = app.DropDown_6.Value;


        end

        function RunButtonPushed(app, event)
app.skip = 0 ;


g = app.gEditField.Value;
l =  app.LengthEditField.Value;
m = app.MassEditField.Value ;
t = app.TimeEditField.Value;
frame = app.FrameRateEditField.Value;
theta = app.AngleinDegreesEditField.Value*pi/180;
omega = app.AngularvelocityEditField.Value;
theta_0 = [theta ; omega];
c = app.ColoroftheBallDropDown.Value;
app.Lamp.Color = c ;

t_span = linspace(0 , t , frame*t);
[t,results] = ode45(@(t,theta)ode_func(t,theta,g,l),t_span,theta_0);

x = l*sin(results(:,1));
y = -l*cos(results(:,1));


axis(app.UIAxes,'equal')
app.UIAxes.set('XLim',[-1.1*(l) 1.1*(l)])
app.UIAxes.set('YLim',[-1.1*(l) 1.1*(l)])
app.UIAxes.set('Color','k')

for i = 1:length(t)
    if app.skip == 1
        i = length(t);
    end
    cla(app.UIAxes);
    hold(app.UIAxes,"on") ;
    plot(app.UIAxes ,[0 x(i)],[0 y(i)],'w','LineWidth', 2 )
    p = plot(app.UIAxes ,0,0,'wo');
    set(p,'MarkerFaceColor','w','MarkerSize', 4 )
    if i < 7 || i == length(t)
        j = 1 ;
    else
        j = i-6 ;
    end
    plot(app.UIAxes,x(j:i),y(j:i),'color','w','LineWidth',1)
    p = plot(app.UIAxes ,x(i),y(i),'r');
    set(p,'Marker','o','MarkerFaceColor',c,'Color','k','MarkerSize',15)
    pause(0.05);
end

app.skip = 0 ;

function [dtheta_dt] = ode_func(t,theta,g,l)
    theta1 = theta(1);
    theta2 = theta(2);
    dtheta1_dt = theta2;
    dtheta2_dt = -(g/l)*sin(theta1);
    dtheta_dt = [dtheta1_dt;dtheta2_dt];
end
        end

        function SkipButtonPushed(app, event)
            app.skip = 1 ;
        end

        function RunButton_2Pushed(app, event)
    app.skip = 0 ;
    mA  = app.MassEditField_2.Value;                 
    mB  = app.MassEditField_3.Value;                     
    rA  = app.LengthAEditField.Value;
    rB  = app.LengthEditField_2.Value;               
    g   = app.gEditField_2.Value;   
    theta_A0      = app.AngleofAEditField.Value*pi/180;                   
    theta_B0      = app.InitialAngleEditField.Value*pi/180;                   
    velocity_A0   = app.AngularvelocityAEditField.Value;                   
    velocity_B0   = app.AngularvelocityEditField_2.Value;                  
    time        = app.TimeEditField_2.Value;  
    framerate   = app.FramerateEditField.Value;  
    [t,results] = solution_to_double_pendulam(app,mA,mB,rA,rB,g,theta_A0,...
        theta_B0,velocity_A0,velocity_B0,time,framerate);
   
    theta_A     = results(:,1);
    velocity_A  = results(:,2);
    theta_B     = results(:,3);
    velocity_B  = results(:,4);
    xA =  rA*sin(theta_A);
    yA = -rA*cos(theta_A);
    xB =  rA*sin(theta_A) + rB*sin(theta_B);
    yB = -rA*cos(theta_A) - rB*cos(theta_B);


    axis(app.UIAxes_2,'equal')
    app.UIAxes_2.set('XLim',[-1.1*(rA+rB) 1.1*(rA+rB)])
    app.UIAxes_2.set('YLim',[-1.1*(rA+rB) 1.1*(rA+rB)])
    app.UIAxes_2.set('Color','k')
    
    for i = 1:length(xA)
        cla(app.UIAxes_2);
        hold(app.UIAxes_2,"on") ;
        if app.skip == 1
            i = length(xA);
        end
        plot(app.UIAxes_2,[0 xA(i)],[0 yA(i)],'w','LineWidth',2)
        plot(app.UIAxes_2,[xA(i) xB(i)],[yA(i) yB(i)],'w','LineWidth',2)
        p = plot(app.UIAxes_2,0,0,'wo');
        set(p,'MarkerFaceColor','w','MarkerSize',4)
   
        plot(app.UIAxes_2,xA(1:i),yA(1:i),'color',app.ColorDropDown_4.Value,'LineWidth',1)
        plot(app.UIAxes_2,xB(1:i),yB(1:i),'color',app.ColorDropDown_5.Value,'LineWidth',1)
        
        p = plot(app.UIAxes_2,xA(i),yA(i),'r');
        set(p,'Marker','o','MarkerFaceColor',app.ColorDropDown_4.Value,'Color','k','MarkerSize',10)
        p = plot(app.UIAxes_2,xB(i),yB(i),'g');
        set(p,'Marker','o','MarkerFaceColor',app.ColorDropDown_5.Value,'Color','k','MarkerSize',10)
        pause(0.00000001);
    end
    app.skip = 0;
        end

        function SkipButton_2Pushed(app, event)
            app.skip = 1;
        end

        function RunButton_3Pushed(app, event)
    time        = app.TimeEditField_3.Value;  
    framerate   = app.FramerateEditField_2.Value;  
    g   = app.gEditField_3.Value;   
    app.skip = 0 ;
    
    mA1  = app.MassAEditField_3.Value;                 
    mB1  = app.MassBEditField_3.Value;                     
    rA1  = app.LengthAEditField_3.Value;
    rB1 = app.LengthBEditField_3.Value;               

    theta_A01      = app.AngleofAEditField_3.Value*pi/180;                   
    theta_B01      = app.AngleofBEditField_3.Value*pi/180;                   
    velocity_A01   = app.AvelocityAEditField_2.Value;                   
    velocity_B01   = app.AvelocityBEditField_2.Value;                


   mA2  = app.MassAEditField_2.Value;                 
   mB2  = app.MassBEditField_2.Value;                     
   rA2  = app.LengthAEditField_2.Value;
   rB2 = app.LengthBEditField_2.Value;               
    
   theta_A02      = app.AngleofAEditField_2.Value*pi/180;                   
   theta_B02      = app.AngleofBEditField_2.Value*pi/180;                   
   velocity_A02   = app.AvelocityAEditField.Value;                   
   velocity_B02   = app.AvelocityBEditField.Value;    


    [t,results] = solution_to_double_pendulam(app,mA1,mB1,rA1,rB1,g,theta_A01,theta_B01,velocity_A01,velocity_B01,time,framerate);

    theta_A1     = results(:,1);
    velocity_A1  = results(:,2);
    theta_B1     = results(:,3);
    velocity_B1  = results(:,4);

    xA1 =  rA1*sin(theta_A1);
    yA1 = -rA1*cos(theta_A1);
    xB1 =  rA1*sin(theta_A1) + rB1*sin(theta_B1);
    yB1 = -rA1*cos(theta_A1) - rB1*cos(theta_B1);

    [t,results] = solution_to_double_pendulam(app,mA2,mB2,rA2,rB2,g,theta_A02,theta_B02,velocity_A02,velocity_B02,time,framerate);

    theta_A2     = results(:,1);
    velocity_A2  = results(:,2);
    theta_B2     = results(:,3);
    velocity_B2  = results(:,4);

    xA2 =  rA2*sin(theta_A2);
    yA2 = -rA2*cos(theta_A2);
    xB2 =  rA2*sin(theta_A2) + rB2*sin(theta_B2);
    yB2 = -rA2*cos(theta_A2) - rB2*cos(theta_B2);


    r = max(rA1+rB1 , rA2+rB2) ;

    axis(app.UIAxes_3,'equal')
    app.UIAxes_3.set('XLim',[-1.1*r 1.1*r])
    app.UIAxes_3.set('YLim',[-1.1*r 1.1*r])
    app.UIAxes_3.set('Color','k')
    
    for i = 1:length(xA1)
        cla(app.UIAxes_3);
        hold(app.UIAxes_3,"on") ;
        if app.skip == 1
            i = length(xA1);
        end
        p = plot(app.UIAxes_3,0,0,'wo');
        set(p,'MarkerFaceColor','w','MarkerSize',4)  

        plot(app.UIAxes_3,[0 xA1(i)],[0 yA1(i)],'w','LineWidth',2)
        plot(app.UIAxes_3,[xA1(i) xB1(i)],[yA1(i) yB1(i)],'w','LineWidth',2)
        plot(app.UIAxes_3,xB1(1:i),yB1(1:i),'color',app.ColorDropDown_3.Value,'LineWidth',1)
        
        p = plot(app.UIAxes_3,xA1(i),yA1(i),'wo');
        set(p,'MarkerFaceColor','w','MarkerSize',7) 
        p = plot(app.UIAxes_3,xB1(i),yB1(i),'g');
        set(p,'Marker','o','MarkerFaceColor',app.ColorDropDown_3.Value,'Color','k','MarkerSize',10)
        
        plot(app.UIAxes_3,[0 xA2(i)],[0 yA2(i)],'w','LineWidth',2)
        plot(app.UIAxes_3,[xA2(i) xB2(i)],[yA2(i) yB2(i)],'w','LineWidth',2)
        plot(app.UIAxes_3,xB2(1:i),yB2(1:i),'color',app.ColorDropDown_2.Value,'LineWidth',1)
        p = plot(app.UIAxes_3,xA2(i),yA2(i),'wo');
        set(p,'MarkerFaceColor','w','MarkerSize',7) 
        p = plot(app.UIAxes_3,xB2(i),yB2(i),'g');
        set(p,'Marker','o','MarkerFaceColor',app.ColorDropDown_2.Value,'Color','k','MarkerSize',10)
        
        pause(0.000000001);
    end

    app.skip = 0;
        end

        function SkipButton_3Pushed(app, event)
            app.skip = 1;
        end


        function ColoroftheBallDropDownValueChanged(app, event)
            value = app.ColoroftheBallDropDown.Value;
            app.Lamp.Color = value;
        end

        function ColorDropDown_4ValueChanged(app, event)
            value = app.ColorDropDown_4.Value;
            app.Lamp_2.Color = value;
        end

        function ColorDropDown_5ValueChanged(app, event)
            value = app.ColorDropDown_5.Value;
            app.Lamp_3.Color = value;
        end

        function ColorDropDown_3ValueChanged(app, event)
            value = app.ColorDropDown_3.Value;
            app.Lamp_4.Color = value;
        end

        function ColorDropDown_2ValueChanged(app, event)
            value = app.ColorDropDown_2.Value;
            app.Lamp_5.Color = value;
        end

        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            app.Lamp_11.Color = value;
        end

        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            app.Lamp_10.Color = value;
        end

        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
            app.Lamp_9.Color = value;
        end

        function DropDown_7ValueChanged(app, event)
            value = app.DropDown_7.Value;
            app.Lamp_8.Color = value ;
        end

        function RunButton_4Pushed(app, event)
            app.skip = 0 ;
    mA  = app.MassAEditField_6.Value;                 
    mB  = app.MassBEditField_6.Value;                     
    rA  = app.LengthAEditField_6.Value;
    rB  = app.LengthBEditField_6.Value;               
    g   = app.gEditField_4.Value;   

    theta_A0      = app.AngleofAEditField_6.Value*pi/180;                   
    theta_B0      = app.AngleofBEditField_6.Value*pi/180;                   
    velocity_A0   = app.AngularvelocityAEditField_2.Value;                   
    velocity_B0   = app.AngularvelocityBEditField_2.Value;                  

    time        = app.TimeEditField_4.Value;  
    framerate   = app.FramerateEditField_3.Value;  

    built = app.BuiltinFunctionCheckBox.Value;
    improve = app.ImprovedEulerMethodCheckBox.Value;
    eulur = app.EulerMethodCheckBox.Value;


    if(built == 1)

    [t,results] = solution_to_double_pendulam(app,mA,mB,rA,rB,g,theta_A0,...
        theta_B0,velocity_A0,velocity_B0,time,framerate);

    theta_A     = results(:,1);
    velocity_A  = results(:,2);
    theta_B     = results(:,3);
    velocity_B  = results(:,4);

    xA1 =  rA*sin(theta_A);
    yA1 = -rA*cos(theta_A);
    xB1 =  rA*sin(theta_A) + rB*sin(theta_B);
    yB1 = -rA*cos(theta_A) - rB*cos(theta_B);

    end
if(improve == 1)
h = 1/framerate;

y1(1) = theta_A0;
y2(1) = velocity_A0;
y3(1) = theta_B0;
y4(1) = velocity_B0;

t = 0 : h : time ;

f2 = @(mA,mB,rA,rB,g,y1,y2,y3,y4) (  (mA+mB)*rA - mB*rA*(cos(y1-y3))^2  )^(-1) * ...
        (  -mB*rA*(y2^2)*sin(y1-y3)*cos(y1-y3)  + mB*g*sin(y3)*cos(y1-y3)  - mB*rB*...
        (y4^2)*sin(y1-y3)  -  (mA+mB)*g*sin(y1)  );

f4 = @(mA,mB,rA,rB,g,y1,y2,y3,y4) (  rB - (mB*rB/(mA+mB))*(cos(y1-y3))^2 )^(-1)  * ...
        ( rA*(y2)^2*sin(y1-y3) - g*sin(y3) + (mB*rB/(mA+mB))*(y4^2)*sin(y1-y3)...
        *cos(y1-y3) + g*sin(y1)*cos(y1-y3) );

for i = 2 : length(t)
    y1(i) = y1(i-1) + h*y2(i-1);
    y3(i) = y3(i-1) + h*y4(i-1);

    u2(i) = y2(i-1) + h*f2(mA,mB,rA,rB,g,y1(i-1),y2(i-1),y3(i-1),y4(i-1));
    u4(i) = y4(i-1) + h*f4(mA,mB,rA,rB,g,y1(i-1),y2(i-1),y3(i-1),y4(i-1));

    y2(i) = y2(i-1) +...
   0.5*h*( f2(mA,mB,rA,rB,g,y1(i-1),y2(i-1),y3(i-1),y4(i-1)) + f2(mA,mB,rA,...
   rB,g,y1(i),u2(i),y3(i),u4(i)) );
    y4(i) = y4(i-1) +...
   0.5*h*( f4(mA,mB,rA,rB,g,y1(i-1),y2(i-1),y3(i-1),y4(i-1)) + f4(mA,mB,rA,...
   rB,g,y1(i),u2(i),y3(i),u4(i)) );
end

theta_A     = y1;
velocity_A  = y2;
theta_B     = y3;
velocity_B  = y4;

xA2 =  rA*sin(theta_A);
yA2 = -rA*cos(theta_A);
xB2 =  rA*sin(theta_A) + rB*sin(theta_B);
yB2 = -rA*cos(theta_A) - rB*cos(theta_B) ;
end

if(eulur == 1)
h = 1/framerate;

y1(1) = theta_A0;
y2(1) = velocity_A0;
y3(1) = theta_B0;
y4(1) = velocity_B0;

t = 0 : h : time ;

f2 = @(mA,mB,rA,rB,g,y1,y2,y3,y4) (  (mA+mB)*rA - mB*rA*(cos(y1-y3))^2  )^(-1) * ...
        (  -mB*rA*(y2^2)*sin(y1-y3)*cos(y1-y3)  + mB*g*sin(y3)*cos(y1-y3)  - ...
        mB*rB*(y4^2)*sin(y1-y3)  -  (mA+mB)*g*sin(y1)  );

f4 = @(mA,mB,rA,rB,g,y1,y2,y3,y4) (  rB - (mB*rB/(mA+mB))*(cos(y1-y3))^2 )^(-1)  * ...
        ( rA*(y2)^2*sin(y1-y3) - g*sin(y3) + (mB*rB/(mA+mB))*(y4^2)*sin(y1-y3)...
        *cos(y1-y3) + g*sin(y1)*cos(y1-y3) );

for i = 2 : length(t)
    y1(i) = y1(i-1) + h*y2(i-1);
    y3(i) = y3(i-1) + h*y4(i-1);
    y2(i) = y2(i-1) + h*f2(mA,mB,rA,rB,g,y1(i-1),y2(i-1),y3(i-1),y4(i-1));
    y4(i) = y4(i-1) + h*f4(mA,mB,rA,rB,g,y1(i-1),y2(i-1),y3(i-1),y4(i-1));
end

theta_A     = y1;
velocity_A  = y2;
theta_B     = y3;
velocity_B  = y4;

xA3 =  rA*sin(theta_A);
yA3 = -rA*cos(theta_A);
xB3 =  rA*sin(theta_A) + rB*sin(theta_B);
yB3 = -rA*cos(theta_A) - rB*cos(theta_B) ;

end

len = length(t);

    axis(app.UIAxes_7,'equal')
    app.UIAxes_7.set('XLim',[-1.1*(rA+rB) 1.1*(rA+rB)])
    app.UIAxes_7.set('YLim',[-1.1*(rA+rB) 1.1*(rA+rB)])
    app.UIAxes_7.set('Color','k')
    
    for i = 1:len 
        cla(app.UIAxes_7);
        hold(app.UIAxes_7,"on") ;
        if app.skip == 1
            i = len;
        end

        if (built == 1)
        plot(app.UIAxes_7,[0 xA1(i)],[0 yA1(i)],'w','LineWidth',2)
        plot(app.UIAxes_7,[xA1(i) xB1(i)],[yA1(i) yB1(i)],'w','LineWidth',2)
        p = plot(app.UIAxes_7,0,0,'wo');
        set(p,'MarkerFaceColor','w','MarkerSize',4)
   
        plot(app.UIAxes_7,xB1(1:i),yB1(1:i),'color',app.DropDown_4.Value,'LineWidth',1)
        
        p = plot(app.UIAxes_7,xA1(i),yA1(i),'r');
        set(p,'Marker','o','MarkerFaceColor','w','Color','k','MarkerSize',10)
        p = plot(app.UIAxes_7,xB1(i),yB1(i),'g');
        set(p,'Marker','o','MarkerFaceColor',app.DropDown_4.Value,'Color','k','MarkerSize',10)
        end
    if(improve == 1)
        plot(app.UIAxes_7,[0 xA2(i)],[0 yA2(i)],'w','LineWidth',2)
        plot(app.UIAxes_7,[xA2(i) xB2(i)],[yA2(i) yB2(i)],'w','LineWidth',2)
        p = plot(app.UIAxes_7,0,0,'wo');
        set(p,'MarkerFaceColor','w','MarkerSize',4)
   
        plot(app.UIAxes_7,xB2(1:i),yB2(1:i),'color',app.DropDown_6.Value,'LineWidth',1)
        
        p = plot(app.UIAxes_7,xA2(i),yA2(i),'r');
        set(p,'Marker','o','MarkerFaceColor','w','Color','k','MarkerSize',10)
        p = plot(app.UIAxes_7,xB2(i),yB2(i),'g');
        set(p,'Marker','o','MarkerFaceColor',app.DropDown_6.Value,'Color','k','MarkerSize',10)
    end
        if(eulur == 1)
        plot(app.UIAxes_7,[0 xA3(i)],[0 yA3(i)],'w','LineWidth',2)
        plot(app.UIAxes_7,[xA3(i) xB3(i)],[yA3(i) yB3(i)],'w','LineWidth',2)
        p = plot(app.UIAxes_7,0,0,'wo');
        set(p,'MarkerFaceColor','w','MarkerSize',4)
   
        plot(app.UIAxes_7,xB3(1:i),yB3(1:i),'color',app.DropDown_5.Value,'LineWidth',1)
        
        p = plot(app.UIAxes_7,xA3(i),yA3(i),'r');
        set(p,'Marker','o','MarkerFaceColor','w','Color','k','MarkerSize',10)
        p = plot(app.UIAxes_7,xB3(i),yB3(i),'g');
        set(p,'Marker','o','MarkerFaceColor',app.DropDown_5.Value,'Color','k','MarkerSize',10)
        
        end
        
    pause(0.000000001);
    end
    app.skip = 0;
        end

        function SkipButton_5Pushed(app, event)
            app.skip = 1;
        end

        function CopyButtonPushed(app, event)
app.MassAEditField_2.Value = app.MassEditField_2.Value;
app.MassAEditField_3.Value = app.MassEditField_2.Value;
app.MassAEditField_6.Value = app.MassEditField_2.Value;

app.MassBEditField_2.Value = app.MassEditField_3.Value;
app.MassBEditField_3.Value = app.MassEditField_3.Value;
app.MassBEditField_6.Value = app.MassEditField_3.Value;

app.LengthAEditField_2.Value = app.LengthAEditField.Value;
app.LengthAEditField_3.Value = app.LengthAEditField.Value;
app.LengthAEditField_6.Value = app.LengthAEditField.Value;

app.LengthBEditField_2.Value = app.LengthEditField_2.Value;
app.LengthBEditField_3.Value = app.LengthEditField_2.Value;
app.LengthBEditField_6.Value = app.LengthEditField_2.Value;

app.AngleofAEditField_2.Value = app.AngleofAEditField.Value;
app.AngleofAEditField_3.Value = app.AngleofAEditField.Value;
app.AngleofAEditField_6.Value = app.AngleofAEditField.Value;

app.AngleofBEditField_2.Value = app.InitialAngleEditField.Value + 1 ;
app.AngleofBEditField_3.Value = app.InitialAngleEditField.Value;
app.AngleofBEditField_6.Value = app.InitialAngleEditField.Value;

app.AvelocityAEditField.Value = app.AngularvelocityAEditField.Value;
app.AvelocityAEditField_2.Value = app.AngularvelocityAEditField.Value;
app.AngularvelocityAEditField_2.Value = app.AngularvelocityAEditField.Value;

app.AvelocityBEditField.Value = app.AngularvelocityEditField_2.Value;
app.AvelocityBEditField_2.Value = app.AngularvelocityEditField_2.Value;
app.AngularvelocityBEditField_2.Value = app.AngularvelocityEditField_2.Value;

app.gEditField_3.Value = app.gEditField_2.Value;
app.gEditField_4.Value = app.gEditField_2.Value;

app.TimeEditField_3.Value = app.TimeEditField_2.Value;
app.TimeEditField_4.Value = app.TimeEditField_2.Value;

app.FramerateEditField_2.Value = app.FramerateEditField.Value;
app.FramerateEditField_3.Value = app.FramerateEditField.Value;
        end

        function ButtonPushed2(app, event)
            app.TabGroup.SelectedTab = app.TheoriticalDiscussionTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntoductionTab = uitab(app.TabGroup);
            app.IntoductionTab.Title = 'Intoduction';
            app.Image3 = uiimage(app.IntoductionTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '235cac1ccebb82eeee8e288870b99cf3.jpg');
            app.UnderstandingtheButterflyEffectLabel = uilabel(app.IntoductionTab);
            app.UnderstandingtheButterflyEffectLabel.Text = 'Understanding the Butterfly-Effect';
            app.DoublePendulumLabel = uilabel(app.IntoductionTab);
            app.DoublePendulumLabel.Text = 'Double Pendulum';
            app.withLabel = uilabel(app.IntoductionTab);
            app.withLabel.Text = 'with';
            app.JoyontaKartikDebnathLabel = uilabel(app.IntoductionTab);
            app.JoyontaKartikDebnathLabel.Text = 'Joyonta Kartik Debnath';
            app.MaksudurRahamanTurzoLabel = uilabel(app.IntoductionTab);
            app.MaksudurRahamanTurzoLabel.Text = 'Maksudur Rahaman Turzo';
            app.Label_2 = uilabel(app.IntoductionTab);
            app.Label_2.Text = '2006054';
            app.Label_3 = uilabel(app.IntoductionTab);
            app.Label_3.Text = '2006053';
            app.Button = uibutton(app.IntoductionTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'imageedit_1_2696692977.png');
            app.Button.Text = '';
            app.TheoriticalDiscussionTab = uitab(app.TabGroup);
            app.TheoriticalDiscussionTab.Title = 'Theoritical Discussion';
            app.TheoryLabel_2 = uilabel(app.TheoriticalDiscussionTab);
            app.TheoryLabel_2.Text = 'Theory:';
            app.TextArea_2 = uitextarea(app.TheoriticalDiscussionTab);
            app.TextArea_2.Value = {'The Butterfly Effect:'; ''; 'The butterfly effect is a concept in chaos theory that refers to the idea that small changes in initial conditions can lead to vastly different outcomes in a system over time. The term was coined by Edward Lorenz, a mathematician who discovered the effect while studying weather patterns.'; 'The butterfly effect suggests that a small change in one part of a system can have a large effect on another part of the system, even if the connection between the two is not immediately obvious. This is because complex systems, such as weather patterns or ecosystems, are often nonlinear and highly sensitive to initial conditions.'};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
            app.SinglePendulumTab = uitab(app.TabGroup);
            app.SinglePendulumTab.Title = 'Single Pendulum';
            app.UIAxes = uiaxes(app.SinglePendulumTab);
            title(app.UIAxes, 'Single Pendulum')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.MassEditFieldLabel = uilabel(app.SinglePendulumTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.SinglePendulumTab, 'numeric');
            app.MassEditField.Value = 2;
            app.LengthEditFieldLabel = uilabel(app.SinglePendulumTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.SinglePendulumTab, 'numeric');
            app.LengthEditField.Value = 0.1;
            app.gEditFieldLabel = uilabel(app.SinglePendulumTab);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.SinglePendulumTab, 'numeric');
            app.gEditField.Value = 9.8;
            app.TimeEditFieldLabel = uilabel(app.SinglePendulumTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.SinglePendulumTab, 'numeric');
            app.TimeEditField.Value = 3;
            app.FrameRateEditFieldLabel = uilabel(app.SinglePendulumTab);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.SinglePendulumTab, 'numeric');
            app.FrameRateEditField.Value = 200;
            app.RunButton = uibutton(app.SinglePendulumTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AngleinDegreesEditFieldLabel = uilabel(app.SinglePendulumTab);
            app.AngleinDegreesEditFieldLabel.Text = 'Angle in Degrees';
            app.AngleinDegreesEditField = uieditfield(app.SinglePendulumTab, 'numeric');
            app.AngleinDegreesEditField.Value = 85;
            app.AngularvelocityEditFieldLabel = uilabel(app.SinglePendulumTab);
            app.AngularvelocityEditFieldLabel.Text = 'Angular velocity';
            app.AngularvelocityEditField = uieditfield(app.SinglePendulumTab, 'numeric');
            app.SkipButton = uibutton(app.SinglePendulumTab, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.Image = uiimage(app.SinglePendulumTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Simple-Pendulum-diagram.png');
            app.Lamp = uilamp(app.SinglePendulumTab);
            app.ColoroftheBallDropDownLabel = uilabel(app.SinglePendulumTab);
            app.ColoroftheBallDropDownLabel.Text = 'Color of the Ball';
            app.ColoroftheBallDropDown = uidropdown(app.SinglePendulumTab);
            app.ColoroftheBallDropDown.Items = {'Blue', 'Green', 'Red', 'Yellow'};
            app.ColoroftheBallDropDown.ValueChangedFcn = createCallbackFcn(app, @ColoroftheBallDropDownValueChanged, true);
            app.ColoroftheBallDropDown.Value = 'Blue';
            app.TextArea = uitextarea(app.SinglePendulumTab);
            app.TextArea.Value = {'l = Length'; 'x = Angle in Degrees'; 'm = Mass'; ''; 'It is sugggested to keep the input values closer to the default ones , for better animation .'};
            app.SinglePendulumLabel = uilabel(app.SinglePendulumTab);
            app.SinglePendulumLabel.Text = 'Single Pendulum';
            app.DoublePendulumTab = uitab(app.TabGroup);
            app.DoublePendulumTab.Title = 'Double Pendulum';
            app.UIAxes_2 = uiaxes(app.DoublePendulumTab);
            title(app.UIAxes_2, 'Double Pendulum')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.MassEditField_2 = uieditfield(app.DoublePendulumTab, 'numeric');
            app.MassEditField_2.Value = 5;
            app.MassEditField_3Label = uilabel(app.DoublePendulumTab);
            app.MassEditField_3Label.Text = 'Mass';
            app.MassEditField_3 = uieditfield(app.DoublePendulumTab, 'numeric');
            app.MassEditField_3.Value = 5;
            app.LengthAEditField = uieditfield(app.DoublePendulumTab, 'numeric');
            app.LengthAEditField.Value = 0.15;
            app.LengthEditField_2Label = uilabel(app.DoublePendulumTab);
            app.LengthEditField_2Label.Text = 'Length ';
            app.LengthEditField_2 = uieditfield(app.DoublePendulumTab, 'numeric');
            app.LengthEditField_2.Value = 0.1;
            app.AngleofAEditField = uieditfield(app.DoublePendulumTab, 'numeric');
            app.AngleofAEditField.Value = 115;
            app.InitialAngleEditFieldLabel = uilabel(app.DoublePendulumTab);
            app.InitialAngleEditFieldLabel.Text = 'Initial Angle ';
            app.InitialAngleEditField = uieditfield(app.DoublePendulumTab, 'numeric');
            app.InitialAngleEditField.Value = 150;
            app.AngularvelocityAEditField = uieditfield(app.DoublePendulumTab, 'numeric');
            app.AngularvelocityEditField_2Label = uilabel(app.DoublePendulumTab);
            app.AngularvelocityEditField_2Label.Text = 'Angular velocity';
            app.AngularvelocityEditField_2 = uieditfield(app.DoublePendulumTab, 'numeric');
            app.gEditField_2Label = uilabel(app.DoublePendulumTab);
            app.gEditField_2Label.Text = 'g';
            app.gEditField_2 = uieditfield(app.DoublePendulumTab, 'numeric');
            app.gEditField_2.Value = 9.8;
            app.TimeEditField_2Label = uilabel(app.DoublePendulumTab);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.DoublePendulumTab, 'numeric');
            app.TimeEditField_2.Value = 5;
            app.FramerateEditFieldLabel = uilabel(app.DoublePendulumTab);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.DoublePendulumTab, 'numeric');
            app.FramerateEditField.Value = 150;
            app.RunButton_2 = uibutton(app.DoublePendulumTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.SkipButton_2 = uibutton(app.DoublePendulumTab, 'push');
            app.SkipButton_2.ButtonPushedFcn = createCallbackFcn(app, @SkipButton_2Pushed, true);
            app.SkipButton_2.Text = 'Skip';
            app.Image2 = uiimage(app.DoublePendulumTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'download.png');
            app.ColorDropDown_5Label = uilabel(app.DoublePendulumTab);
            app.ColorDropDown_5Label.Text = 'Color';
            app.ColorDropDown_5 = uidropdown(app.DoublePendulumTab);
            app.ColorDropDown_5.Items = {'Blue', 'Green', 'Red', 'Yellow', 'White'};
            app.ColorDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_5ValueChanged, true);
            app.ColorDropDown_5.Value = 'Blue';
            app.ColorDropDown_4 = uidropdown(app.DoublePendulumTab);
            app.ColorDropDown_4.Items = {'Red', 'Green', 'Yellow', 'Blue', 'White'};
            app.ColorDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_4ValueChanged, true);
            app.ColorDropDown_4.Value = 'Red';
            app.Lamp_2 = uilamp(app.DoublePendulumTab);
            app.Lamp_3 = uilamp(app.DoublePendulumTab);
            app.DoublePendulumLabel_2 = uilabel(app.DoublePendulumTab);
            app.DoublePendulumLabel_2.Text = 'Double Pendulum';
            app.BallALabel = uilabel(app.DoublePendulumTab);
            app.BallALabel.Text = 'Ball A';
            app.BallBLabel = uilabel(app.DoublePendulumTab);
            app.BallBLabel.Text = 'Ball B';
            app.TextArea_3 = uitextarea(app.DoublePendulumTab);
            app.TextArea_3.Value = {'l1 & l2 = Length'; 'a1 & a2 = Angle in Degrees'; 'm1 & m2 = Mass'; 'y1 & y2 = displacement in y'; 'x1 & x2 = displacement in x'; ''; 'It is sugggested to keep the input values closer to the default ones , for better animation .'};
            app.CopyButton = uibutton(app.DoublePendulumTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.ButterflyEffectTab = uitab(app.TabGroup);
            app.ButterflyEffectTab.Title = 'Butterfly Effect';
            app.UIAxes_3 = uiaxes(app.ButterflyEffectTab);
            title(app.UIAxes_3, 'Two Double Pendulums')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.MassAEditField_2Label = uilabel(app.ButterflyEffectTab);
            app.MassAEditField_2Label.Text = 'Mass A';
            app.MassAEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.MassAEditField_2.Value = 5;
            app.MassBEditField_2Label = uilabel(app.ButterflyEffectTab);
            app.MassBEditField_2Label.Text = 'Mass B';
            app.MassBEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.MassBEditField_2.Value = 5;
            app.LengthAEditField_2Label = uilabel(app.ButterflyEffectTab);
            app.LengthAEditField_2Label.Text = 'Length A';
            app.LengthAEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.LengthAEditField_2.Value = 0.15;
            app.LengthBEditField_2Label = uilabel(app.ButterflyEffectTab);
            app.LengthBEditField_2Label.Text = 'Length B';
            app.LengthBEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.LengthBEditField_2.Value = 0.1;
            app.AngleofAEditField_2Label = uilabel(app.ButterflyEffectTab);
            app.AngleofAEditField_2Label.Text = 'Angle of A';
            app.AngleofAEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.AngleofAEditField_2.Value = 115;
            app.AngleofBEditField_2Label = uilabel(app.ButterflyEffectTab);
            app.AngleofBEditField_2Label.Text = 'Angle of B';
            app.AngleofBEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.AngleofBEditField_2.Value = 151;
            app.AvelocityAEditFieldLabel = uilabel(app.ButterflyEffectTab);
            app.AvelocityAEditFieldLabel.Text = 'A. velocity A';
            app.AvelocityAEditField = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.AvelocityBEditFieldLabel = uilabel(app.ButterflyEffectTab);
            app.AvelocityBEditFieldLabel.Text = 'A. velocity B';
            app.AvelocityBEditField = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.gEditField_3Label = uilabel(app.ButterflyEffectTab);
            app.gEditField_3Label.Text = 'g';
            app.gEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.gEditField_3.Value = 9.8;
            app.TimeEditField_3Label = uilabel(app.ButterflyEffectTab);
            app.TimeEditField_3Label.Text = 'Time';
            app.TimeEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.TimeEditField_3.Value = 5;
            app.FramerateEditField_2Label = uilabel(app.ButterflyEffectTab);
            app.FramerateEditField_2Label.Text = 'Frame rate';
            app.FramerateEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.FramerateEditField_2.Value = 150;
            app.MassAEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.MassAEditField_3.Value = 5;
            app.MassBEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.MassBEditField_3.Value = 5;
            app.LengthAEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.LengthAEditField_3.Value = 0.15;
            app.LengthBEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.LengthBEditField_3.Value = 0.1;
            app.AngleofAEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.AngleofAEditField_3.Value = 115;
            app.AngleofBEditField_3 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.AngleofBEditField_3.Value = 150;
            app.AvelocityAEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.AvelocityBEditField_2 = uieditfield(app.ButterflyEffectTab, 'numeric');
            app.PendulumLabel_2 = uilabel(app.ButterflyEffectTab);
            app.PendulumLabel_2.Text = 'Pendulum';
            app.PendulumLabel = uilabel(app.ButterflyEffectTab);
            app.PendulumLabel.Text = 'Pendulum';
            app.RunButton_3 = uibutton(app.ButterflyEffectTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.SkipButton_3 = uibutton(app.ButterflyEffectTab, 'push');
            app.SkipButton_3.ButtonPushedFcn = createCallbackFcn(app, @SkipButton_3Pushed, true);
            app.SkipButton_3.Text = 'Skip';
            app.ColorDropDown_2Label = uilabel(app.ButterflyEffectTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.ButterflyEffectTab);
            app.ColorDropDown_2.Items = {'Blue', 'Green', 'Red', 'Yellow', 'White'};
            app.ColorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_2ValueChanged, true);
            app.ColorDropDown_2.Value = 'Blue';
            app.ColorDropDown_3 = uidropdown(app.ButterflyEffectTab);
            app.ColorDropDown_3.Items = {'Red', 'Green', 'Blue', 'Yellow', 'White'};
            app.ColorDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_3ValueChanged, true);
            app.ColorDropDown_3.Value = 'Blue';
            app.Lamp_4 = uilamp(app.ButterflyEffectTab);
            app.Lamp_5 = uilamp(app.ButterflyEffectTab);
            app.ButterflyEffectLabel = uilabel(app.ButterflyEffectTab);
            app.ButterflyEffectLabel.Text = 'Butterfly Effect';
            app.TextArea_4 = uitextarea(app.ButterflyEffectTab);
            app.TextArea_4.Value = {'In the comparison of two double pendulum , we can clearly see a butterfly effect if we change the initial conditions slightly . As double pendulum has a cheotic motion it causes a major difference in final condition for a slight change in the initial conditions .'};
            app.ComparisonofIterationsTab = uitab(app.TabGroup);
            app.ComparisonofIterationsTab.Title = 'Comparison of Iterations';
            app.UIAxes_7 = uiaxes(app.ComparisonofIterationsTab);
            title(app.UIAxes_7, 'Comparison of Iterations')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.MassAEditField_6Label = uilabel(app.ComparisonofIterationsTab);
            app.MassAEditField_6Label.Text = 'Mass A';
            app.MassAEditField_6 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.MassAEditField_6.Value = 5;
            app.MassBEditField_6Label = uilabel(app.ComparisonofIterationsTab);
            app.MassBEditField_6Label.Text = 'Mass B';
            app.MassBEditField_6 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.MassBEditField_6.Value = 5;
            app.LengthAEditField_6Label = uilabel(app.ComparisonofIterationsTab);
            app.LengthAEditField_6Label.Text = 'Length A';
            app.LengthAEditField_6 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.LengthAEditField_6.Value = 0.15;
            app.LengthBEditField_6Label = uilabel(app.ComparisonofIterationsTab);
            app.LengthBEditField_6Label.Text = 'Length B';
            app.LengthBEditField_6 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.LengthBEditField_6.Value = 0.1;
            app.AngleofAEditField_6Label = uilabel(app.ComparisonofIterationsTab);
            app.AngleofAEditField_6Label.Text = 'Angle of A';
            app.AngleofAEditField_6 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.AngleofAEditField_6.Value = 115;
            app.AngleofBEditField_6Label = uilabel(app.ComparisonofIterationsTab);
            app.AngleofBEditField_6Label.Text = 'Angle of B';
            app.AngleofBEditField_6 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.AngleofBEditField_6.Value = 150;
            app.AngularvelocityAEditField_2Label = uilabel(app.ComparisonofIterationsTab);
            app.AngularvelocityAEditField_2Label.Text = 'Angular velocity A';
            app.AngularvelocityAEditField_2 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.AngularvelocityBEditField_2Label = uilabel(app.ComparisonofIterationsTab);
            app.AngularvelocityBEditField_2Label.Text = 'Angular velocity B';
            app.AngularvelocityBEditField_2 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.gEditField_4Label = uilabel(app.ComparisonofIterationsTab);
            app.gEditField_4Label.Text = 'g';
            app.gEditField_4 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.gEditField_4.Value = 9.8;
            app.TimeEditField_4Label = uilabel(app.ComparisonofIterationsTab);
            app.TimeEditField_4Label.Text = 'Time';
            app.TimeEditField_4 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.TimeEditField_4.Value = 5;
            app.FramerateEditField_3Label = uilabel(app.ComparisonofIterationsTab);
            app.FramerateEditField_3Label.Text = 'Frame rate';
            app.FramerateEditField_3 = uieditfield(app.ComparisonofIterationsTab, 'numeric');
            app.FramerateEditField_3.Value = 150;
            app.RunButton_4 = uibutton(app.ComparisonofIterationsTab, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.SkipButton_5 = uibutton(app.ComparisonofIterationsTab, 'push');
            app.SkipButton_5.ButtonPushedFcn = createCallbackFcn(app, @SkipButton_5Pushed, true);
            app.SkipButton_5.Text = 'Skip';
            app.BuiltinFunctionCheckBox = uicheckbox(app.ComparisonofIterationsTab);
            app.BuiltinFunctionCheckBox.Text = 'Built in Function';
            app.EulerMethodCheckBox = uicheckbox(app.ComparisonofIterationsTab);
            app.EulerMethodCheckBox.Text = 'Euler Method';
            app.ImprovedEulerMethodCheckBox = uicheckbox(app.ComparisonofIterationsTab);
            app.ImprovedEulerMethodCheckBox.Text = 'Improved Euler Method';
            app.Lamp_9 = uilamp(app.ComparisonofIterationsTab);
            app.Lamp_10 = uilamp(app.ComparisonofIterationsTab);
            app.Lamp_11 = uilamp(app.ComparisonofIterationsTab);
            app.DropDown_4 = uidropdown(app.ComparisonofIterationsTab);
            app.DropDown_4.Items = {'Red', 'Green', 'Blue', 'Yellow', 'White'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'Red';
            app.DropDown_5 = uidropdown(app.ComparisonofIterationsTab);
            app.DropDown_5.Items = {'Green', 'Red', 'Blue', 'Yellow', 'White'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'Green';
            app.DropDown_6 = uidropdown(app.ComparisonofIterationsTab);
            app.DropDown_6.Items = {'Blue', 'Red', 'Green', 'Yellow', 'White'};
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = 'Blue';
            app.ComparisonofIterativeMethodsLabel = uilabel(app.ComparisonofIterationsTab);
            app.ComparisonofIterativeMethodsLabel.Text = 'Comparison of Iterative Methods';
            app.TextArea_5 = uitextarea(app.ComparisonofIterationsTab);
            app.TextArea_5.Value = {'This part gives a visual comparison of diffeerent iterative methods of solving differencial equations which are discussed in the lab . The slight errors in the iterations cause a butterfly effect in the cheotic system  .'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end