classdef test_screening < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RunButton                matlab.ui.control.Button
        timeEditField            matlab.ui.control.NumericEditField
        timeEditFieldLabel       matlab.ui.control.Label
        framerateEditField       matlab.ui.control.NumericEditField
        framerateEditFieldLabel  matlab.ui.control.Label
        mBEditField              matlab.ui.control.NumericEditField
        mBEditFieldLabel         matlab.ui.control.Label
        omegaBEditField          matlab.ui.control.NumericEditField
        omegaBEditFieldLabel     matlab.ui.control.Label
        thetaBEditField          matlab.ui.control.NumericEditField
        thetaBEditFieldLabel     matlab.ui.control.Label
        omegaAEditField          matlab.ui.control.NumericEditField
        omegaAEditFieldLabel     matlab.ui.control.Label
        thetaAEditField          matlab.ui.control.NumericEditField
        thetaAEditFieldLabel     matlab.ui.control.Label
        lBEditField              matlab.ui.control.NumericEditField
        lBEditFieldLabel         matlab.ui.control.Label
        lAEditField              matlab.ui.control.NumericEditField
        lAEditFieldLabel         matlab.ui.control.Label
        gEditField               matlab.ui.control.NumericEditField
        gEditFieldLabel          matlab.ui.control.Label
        mAEditField              matlab.ui.control.NumericEditField
        mAEditFieldLabel         matlab.ui.control.Label
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    mA  = app.mAEditField.Value;                 
    mB  = app.mBEditField.Value;                     
    rA  = app.lAEditField.Value;
    rB  = app.lBEditField.Value;               
    g   = app.gEditField.Value;   

    theta_A0      = app.thetaAEditField.Value*pi/180;                   
    theta_B0      = app.thetaBEditField.Value*pi/180;                   
    velocity_A0   = app.omegaAEditField.Value;                   
    velocity_B0   = app.omegaBEditField.Value;                  

    time        = app.timeEditField.Value;  
    framerate   = app.framerateEditField.Value;  


    [t,results] = solution_to_double_pendulam(mA,mB,rA,rB,g,theta_A0,theta_B0,velocity_A0,velocity_B0,time,framerate);

    theta_A     = results(:,1);
    velocity_A  = results(:,2);
    theta_B     = results(:,3);
    velocity_B  = results(:,4);

    xA =  rA*sin(theta_A);
    yA = -rA*cos(theta_A);
    xB =  rA*sin(theta_A) + rB*sin(theta_B);
    yB = -rA*cos(theta_A) - rB*cos(theta_B);

    axis(app.UIAxes_2,'equal')
    app.UIAxes_2.set('XLim',[-1.1*(rA+rB) 1.1*(rA+rB)])
    app.UIAxes_2.set('YLim',[-1.1*(rA+rB) 1.1*(rA+rB)])
    app.UIAxes_2.set('Color','k')
    
    for i = 1:length(xA)
        cla(app.UIAxes_2);
        hold(app.UIAxes_2,"on") ;
    
        % Rod 1
        plot(app.UIAxes_2,[0 xA(i)],[0 yA(i)],'w','LineWidth',2)
        % Rod 2
        plot(app.UIAxes_2,[xA(i) xB(i)],[yA(i) yB(i)],'w','LineWidth',2)
    
        % Origin
        p = plot(app.UIAxes_2,0,0,'wo');
        set(p,'MarkerFaceColor','r','MarkerSize',4)
   
        % Trajectory A
        plot(app.UIAxes_2,xA(1:i),yA(1:i),'color','w','LineWidth',1)
        % Trajecotry B
        plot(app.UIAxes_2,xB(1:i),yB(1:i),'color','w','LineWidth',1)
        
        % Position A
        p = plot(app.UIAxes_2,xA(i),yA(i),'r');
        set(p,'Marker','o','MarkerFaceColor','blue','MarkerSize',15)
        % Position B
        p = plot(app.UIAxes_2,xB(i),yB(i),'g');
        set(p,'Marker','o','MarkerFaceColor','blue','Color','k','MarkerSize',15)
    pause(0.001);
 
    end

function dx = double_pendulum_model(t,y,parameters)

    mA  = parameters(1);        % Mass A                [kg]
    mB  = parameters(2);        % Mass B                [kg]
    lA  = parameters(3);        % Length rod A          [m]
    lB  = parameters(4);        % Length rod B          [m]
    g   = parameters(5);        % Gravity               [m/s2]
   
    y1     = y(1);
    y2     = y(2);
    y3     = y(3);
    y4     = y(4);

    out(1) = y2;
    out(2) = (  (mA+mB)*lA - mB*lA*(cos(y1-y3))^2  )^(-1) * ...
        (  -mB*lA*(y2^2)*sin(y1-y3)*cos(y1-y3)  + mB*g*sin(y3)*cos(y1-y3)  - mB*lB*(y4^2)*sin(y1-y3)  -  (mA+mB)*g*sin(y1)  );
    out(3) = y4;
    out(4) = (  lB - (mB*lB/(mA+mB))*(cos(y1-y3))^2 )^(-1)  * ...
        ( lA*(y2)^2*sin(y1-y3) - g*sin(y3) + (mB*lB/(mA+mB))*(y4^2)*sin(y1-y3)*cos(y1-y3) + g*sin(y1)*cos(y1-y3) );
    
    dx = [out(1) ; out(2) ; out(3) ; out(4)];
    end

function [t , results] = solution_to_double_pendulam...
    (mA , mB , lA , lB , g , theta_A0 , theta_B0 ,...
    velocity_A0 , velocity_B0 , tf , fR )

    parameters = [mA mB lA lB g];
    theta_0 = [theta_A0 velocity_A0 theta_B0 velocity_B0];
                              
    dt      = 1/fR;                 
    time_span    = 0 : dt : tf ;

    [ t , results ] = ode45(@(time,theta) double_pendulum_model(time,theta,parameters),time_span,theta_0);

end             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Double Pendulam')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.mAEditFieldLabel = uilabel(app.UIFigure);
            app.mAEditFieldLabel.Text = 'mA';
            app.mAEditField = uieditfield(app.UIFigure, 'numeric');
            app.mAEditField.Value = 2;
            app.gEditFieldLabel = uilabel(app.UIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.UIFigure, 'numeric');
            app.gEditField.Value = 9.8;
            app.lAEditFieldLabel = uilabel(app.UIFigure);
            app.lAEditFieldLabel.Text = 'lA';
            app.lAEditField = uieditfield(app.UIFigure, 'numeric');
            app.lAEditField.Value = 0.1;
            app.lBEditFieldLabel = uilabel(app.UIFigure);
            app.lBEditFieldLabel.Text = 'lB';
            app.lBEditField = uieditfield(app.UIFigure, 'numeric');
            app.lBEditField.Value = 0.1;
            app.thetaAEditFieldLabel = uilabel(app.UIFigure);
            app.thetaAEditFieldLabel.Text = 'thetaA';
            app.thetaAEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaAEditField.Value = 60;
            app.omegaAEditFieldLabel = uilabel(app.UIFigure);
            app.omegaAEditFieldLabel.Text = 'omegaA';
            app.omegaAEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaBEditFieldLabel = uilabel(app.UIFigure);
            app.thetaBEditFieldLabel.Text = 'thetaB';
            app.thetaBEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaBEditField.Value = 65;
            app.omegaBEditFieldLabel = uilabel(app.UIFigure);
            app.omegaBEditFieldLabel.Text = 'omegaB';
            app.omegaBEditField = uieditfield(app.UIFigure, 'numeric');
            app.mBEditFieldLabel = uilabel(app.UIFigure);
            app.mBEditFieldLabel.Text = 'mB';
            app.mBEditField = uieditfield(app.UIFigure, 'numeric');
            app.mBEditField.Value = 2;
            app.framerateEditFieldLabel = uilabel(app.UIFigure);
            app.framerateEditFieldLabel.Text = 'frame rate';
            app.framerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.framerateEditField.Value = 30;
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeEditField.Value = 5;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_screening

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end